classdef SCAPE_Config_Uploader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SCAPEConfigUploaderLabel        matlab.ui.control.Label
        SCAPEConfigNumberEditFieldLabel  matlab.ui.control.Label
        SCAPEConfigNumberEditField      matlab.ui.control.EditField
        SCAPESystemDropDownLabel        matlab.ui.control.Label
        SCAPESystemDropDown             matlab.ui.control.DropDown
        SCAPEConfigDateLabel            matlab.ui.control.Label
        LaserCouplingDropDownLabel      matlab.ui.control.Label
        LaserCouplingDropDown           matlab.ui.control.DropDown
        MagnificationEditFieldLabel     matlab.ui.control.Label
        MagnificationEditField          matlab.ui.control.NumericEditField
        GalvoCalibrationEditFieldLabel  matlab.ui.control.Label
        GalvoCalibrationEditField       matlab.ui.control.NumericEditField
        umpervoltLabel                  matlab.ui.control.Label
        LaserPanel                      matlab.ui.container.Panel
        Laser1DropDownLabel             matlab.ui.control.Label
        Laser1DropDown                  matlab.ui.control.DropDown
        Laser2DropDownLabel             matlab.ui.control.Label
        Laser2DropDown                  matlab.ui.control.DropDown
        Laser3DropDownLabel             matlab.ui.control.Label
        Laser3DropDown                  matlab.ui.control.DropDown
        Laser4DropDownLabel             matlab.ui.control.Label
        Laser4DropDown                  matlab.ui.control.DropDown
        Laser5DropDownLabel             matlab.ui.control.Label
        Laser5DropDown                  matlab.ui.control.DropDown
        Laser6DropDownLabel             matlab.ui.control.Label
        Laser6DropDown                  matlab.ui.control.DropDown
        ObjectivePanel                  matlab.ui.container.Panel
        Objective1DropDownLabel         matlab.ui.control.Label
        Objective1DropDown              matlab.ui.control.DropDown
        Objective2DropDownLabel         matlab.ui.control.Label
        Objective2DropDown              matlab.ui.control.DropDown
        Objective3DropDownLabel         matlab.ui.control.Label
        Objective3DropDown              matlab.ui.control.DropDown
        DichroicPanel                   matlab.ui.container.Panel
        FilterDropDownLabel             matlab.ui.control.Label
        FilterDropDown                  matlab.ui.control.DropDown
        ConfirmButton                   matlab.ui.control.Button
        IvedoubleCheckedCheckBox        matlab.ui.control.CheckBox
        DescriptionEditFieldLabel       matlab.ui.control.Label
        DescriptionEditField            matlab.ui.control.EditField
        mmTubeLensLabel                 matlab.ui.control.Label
    end


    properties (Access = public)
        SCAPEConfigDateDate           % matlab.uidatepicker
        laser_num
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            microscope_schema = microscope.getSchema;
            
            app.SCAPESystemDropDown.Items = (fetchn(microscope_schema.v.ScapeSystem,'scape_name'))';
            
            app.SCAPEConfigDateDate = uidatepicker(app.UIFigure,'Position',[620 384 90 22]);
            
            answer = inputdlg({'Number of lasers (Max. 6)'});
            app.laser_num = str2double(answer{1});
            
            for ii = 1:6
                if ii <= app.laser_num
                    app.(sprintf('Laser%dDropDown', ii)).Items = (fetchn(microscope_schema.v.Laser,'laser'))';
                else
                    app.(sprintf('Laser%dDropDown', ii)).Visible = 0;
                    app.(sprintf('Laser%dDropDownLabel', ii)).Visible = 0;
                end
            end
            
            app.Objective1DropDown.Items = (fetchn(microscope_schema.v.Objective,'objective'))';
            app.Objective2DropDown.Items = (fetchn(microscope_schema.v.Objective,'objective'))';
            app.Objective3DropDown.Items = (fetchn(microscope_schema.v.Objective,'objective'))';
            
            app.FilterDropDown.Items = ['NULL' (fetchn(microscope_schema.v.Filter,'filter_part_number'))'];
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            if app.IvedoubleCheckedCheckBox.Value
                setup_schema = setup.getSchema;
                config_data = struct('scape_config_number',app.SCAPEConfigNumberEditField.Value,'scape_name',app.SCAPESystemDropDown.Value,'scape_config_date',datestr(app.SCAPEConfigDateDate.Value,'YYYY-mm-DD'),...
                    'sys_description',app.DescriptionEditField.Value,'laser_coupling',app.LaserCouplingDropDown.Value,'scape_magnification',app.MagnificationEditField.Value,...
                    'calibration_galvo',app.GalvoCalibrationEditField.Value);
                if ~strcmp(app.FilterDropDown.Value, 'NULL')
                    config_data.Imagesplitter=app.FilterDropDown.Value;
                end
                insert(setup_schema.v.Scapeconfig, config_data);
                for ii = 1:app.laser_num
                    insert(setup_schema.v.ScapeconfigLaser, struct('scape_config_number',app.SCAPEConfigNumberEditField.Value,'laser_id',ii,'laser',app.(sprintf('Laser%dDropDown', ii)).Value));
                end
                for ii = 1:3
                    insert(setup_schema.v.ScapeconfigObjective, struct('scape_config_number',app.SCAPEConfigNumberEditField.Value,'objective_id',ii,'objective',app.(sprintf('Objective%dDropDown', ii)).Value));
                end
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SCAPEConfigUploaderLabel = uilabel(app.UIFigure);
            app.SCAPEConfigUploaderLabel.Text = 'SCAPE Config Uploader';
            app.SCAPEConfigNumberEditFieldLabel = uilabel(app.UIFigure);
            app.SCAPEConfigNumberEditFieldLabel.Text = 'SCAPE Config Number';
            app.SCAPEConfigNumberEditField = uieditfield(app.UIFigure, 'text');
            app.SCAPESystemDropDownLabel = uilabel(app.UIFigure);
            app.SCAPESystemDropDownLabel.Text = 'SCAPE System';
            app.SCAPESystemDropDown = uidropdown(app.UIFigure);
            app.SCAPESystemDropDown.Items = {'Super SCAPE', 'Option 2', 'Option 3', 'Option 4'};
            app.SCAPESystemDropDown.Value = 'Super SCAPE';
            app.SCAPEConfigDateLabel = uilabel(app.UIFigure);
            app.SCAPEConfigDateLabel.Text = 'SCAPE Config Date';
            app.LaserCouplingDropDownLabel = uilabel(app.UIFigure);
            app.LaserCouplingDropDownLabel.Text = 'Laser Coupling';
            app.LaserCouplingDropDown = uidropdown(app.UIFigure);
            app.LaserCouplingDropDown.Items = {'Dichroic', 'Mirror'};
            app.LaserCouplingDropDown.Value = 'Dichroic';
            app.MagnificationEditFieldLabel = uilabel(app.UIFigure);
            app.MagnificationEditFieldLabel.Text = 'Magnification';
            app.MagnificationEditField = uieditfield(app.UIFigure, 'numeric');
            app.GalvoCalibrationEditFieldLabel = uilabel(app.UIFigure);
            app.GalvoCalibrationEditFieldLabel.Text = 'Galvo Calibration';
            app.GalvoCalibrationEditField = uieditfield(app.UIFigure, 'numeric');
            app.GalvoCalibrationEditField.ValueDisplayFormat = '%5.2f';
            app.umpervoltLabel = uilabel(app.UIFigure);
            app.umpervoltLabel.Text = '(um per volt)';
            app.LaserPanel = uipanel(app.UIFigure);
            app.LaserPanel.Title = 'Laser';
            app.Laser1DropDownLabel = uilabel(app.LaserPanel);
            app.Laser1DropDownLabel.Text = 'Laser 1';
            app.Laser1DropDown = uidropdown(app.LaserPanel);
            app.Laser2DropDownLabel = uilabel(app.LaserPanel);
            app.Laser2DropDownLabel.Text = 'Laser 2';
            app.Laser2DropDown = uidropdown(app.LaserPanel);
            app.Laser3DropDownLabel = uilabel(app.LaserPanel);
            app.Laser3DropDownLabel.Text = 'Laser 3';
            app.Laser3DropDown = uidropdown(app.LaserPanel);
            app.Laser4DropDownLabel = uilabel(app.LaserPanel);
            app.Laser4DropDownLabel.Text = 'Laser 4';
            app.Laser4DropDown = uidropdown(app.LaserPanel);
            app.Laser5DropDownLabel = uilabel(app.LaserPanel);
            app.Laser5DropDownLabel.Text = 'Laser 5';
            app.Laser5DropDown = uidropdown(app.LaserPanel);
            app.Laser6DropDownLabel = uilabel(app.LaserPanel);
            app.Laser6DropDownLabel.Text = 'Laser 6';
            app.Laser6DropDown = uidropdown(app.LaserPanel);
            app.ObjectivePanel = uipanel(app.UIFigure);
            app.ObjectivePanel.Title = 'Objective';
            app.Objective1DropDownLabel = uilabel(app.ObjectivePanel);
            app.Objective1DropDownLabel.Text = 'Objective 1';
            app.Objective1DropDown = uidropdown(app.ObjectivePanel);
            app.Objective2DropDownLabel = uilabel(app.ObjectivePanel);
            app.Objective2DropDownLabel.Text = 'Objective 2';
            app.Objective2DropDown = uidropdown(app.ObjectivePanel);
            app.Objective3DropDownLabel = uilabel(app.ObjectivePanel);
            app.Objective3DropDownLabel.Text = 'Objective 3';
            app.Objective3DropDown = uidropdown(app.ObjectivePanel);
            app.DichroicPanel = uipanel(app.UIFigure);
            app.DichroicPanel.Title = 'Dichroic';
            app.FilterDropDownLabel = uilabel(app.DichroicPanel);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.DichroicPanel);
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm!';
            app.IvedoubleCheckedCheckBox = uicheckbox(app.UIFigure);
            app.IvedoubleCheckedCheckBox.Text = 'I''ve double Checked';
            app.DescriptionEditFieldLabel = uilabel(app.UIFigure);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.DescriptionEditField = uieditfield(app.UIFigure, 'text');
            app.mmTubeLensLabel = uilabel(app.UIFigure);
            app.mmTubeLensLabel.Text = '(70 mm Tube Lens)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SCAPE_Config_Uploader

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end