classdef SCAPE_datajoint < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ListBox                        matlab.ui.control.ListBox
        RecorderDropDownLabel          matlab.ui.control.Label
        RecorderDropDown               matlab.ui.control.DropDown
        ProjectDropDownLabel           matlab.ui.control.Label
        ProjectDropDown                matlab.ui.control.DropDown
        ServerLocationEditFieldLabel   matlab.ui.control.Label
        ServerLocationEditField        matlab.ui.control.EditField
        BackupLocationEditFieldLabel   matlab.ui.control.Label
        BackupLocationEditField        matlab.ui.control.EditField
        Panel                          matlab.ui.container.Panel
        NoteEditFieldLabel             matlab.ui.control.Label
        NoteEditField                  matlab.ui.control.EditField
        RunConditionDropDownLabel      matlab.ui.control.Label
        RunConditionDropDown           matlab.ui.control.DropDown
        SpecimenEditFieldLabel         matlab.ui.control.Label
        SpecimenEditField              matlab.ui.control.EditField
        DualColourCheckBox             matlab.ui.control.CheckBox
        ScanSizeGBEditFieldLabel       matlab.ui.control.Label
        ScanSizeGBEditField            matlab.ui.control.NumericEditField
        ScanStatusDropDownLabel        matlab.ui.control.Label
        ScanStatusDropDown             matlab.ui.control.DropDown
        ScannerTypeDropDownLabel       matlab.ui.control.Label
        ScannerTypeDropDown            matlab.ui.control.DropDown
        OrganDropDownLabel             matlab.ui.control.Label
        OrganDropDown                  matlab.ui.control.DropDown
        BehavioralSetupDropDownLabel   matlab.ui.control.Label
        BehavioralSetupDropDown        matlab.ui.control.DropDown
        StimulusSetupDropDownLabel     matlab.ui.control.Label
        StimulusSetupDropDown          matlab.ui.control.DropDown
        FilterEditFieldLabel           matlab.ui.control.Label
        FilterEditField                matlab.ui.control.EditField
        Panel_2                        matlab.ui.container.Panel
        UnitDropDownLabel              matlab.ui.control.Label
        UnitDropDown                   matlab.ui.control.DropDown
        AgeEditFieldLabel              matlab.ui.control.Label
        AgeEditField                   matlab.ui.control.NumericEditField
        DevelopmentStageDropDownLabel  matlab.ui.control.Label
        DevelopmentStageDropDown       matlab.ui.control.DropDown
        TabGroup                       matlab.ui.container.TabGroup
        Camera1Tab                     matlab.ui.container.Tab
        CameraDropDownLabel            matlab.ui.control.Label
        CameraDropDown                 matlab.ui.control.DropDown
        TubeLensDropDownLabel          matlab.ui.control.Label
        TubeLensDropDown               matlab.ui.control.DropDown
        FocalLengthZoomEditFieldLabel  matlab.ui.control.Label
        FocalLengthZoomEditField       matlab.ui.control.NumericEditField
        Camera2Tab                     matlab.ui.container.Tab
        CameraDropDown_2Label          matlab.ui.control.Label
        CameraDropDown_2               matlab.ui.control.DropDown
        TubeLensDropDown_2Label        matlab.ui.control.Label
        TubeLensDropDown_2             matlab.ui.control.DropDown
        FocalLengthZoomEditField_2Label  matlab.ui.control.Label
        FocalLengthZoomEditField_2     matlab.ui.control.NumericEditField
        DeployCheckBox                 matlab.ui.control.CheckBox
        SCAPEConfigDropDownLabel       matlab.ui.control.Label
        SCAPEConfigDropDown            matlab.ui.control.DropDown
        UITable                        matlab.ui.control.Table
        UITable2                       matlab.ui.control.Table
        UploadButton                   matlab.ui.control.Button
    end

    
    properties (Constant)
        DefaultUser = 'Richard_Yan';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Prompt for session folder
            [codepath,~,~] = fileparts(mfilename('fullpath'));
            addpath(codepath);
            selpath = uigetdir(matlabroot,'Select Session Folder');
            cd(selpath);
            
            filesInDataDirectory = dir('*info.mat');
            [~,idx] = sort({filesInDataDirectory.date});
            filesInDataDirectory = filesInDataDirectory(idx);
            runNames = cell(1,length(filesInDataDirectory));
            for i = 1:length(filesInDataDirectory)
                tmpname = filesInDataDirectory(i).name;
                runNames(i) = cellstr(tmpname(1:end-9));
            end
            app.ListBox.Items = runNames; 
            app.ListBox.Value = runNames(1);
            
            %% Connect with Datajoint
            lab_schema = lab.getSchema;
            microscope_schema = microscope.getSchema;
            organism_schema = organism.getSchema;
            setup_schema = setup.getSchema;
            
            %% Apps initialization
            app.RecorderDropDown.Items = (fetchn(lab_schema.v.LabMember,'user'))';
            app.RecorderDropDown.Value = app.DefaultUser;
            app.ProjectDropDown.Items = ['' (fetchn(lab_schema.v.Project,'project'))'];
            app.SCAPEConfigDropDown.Items = (fetchn(setup_schema.v.Scapeconfig,'scape_config_number'))';
            app.OrganDropDown.Items = ['' (fetchn(organism_schema.v.Organ,'organ'))'];
            app.BehavioralSetupDropDown.Items = ['' (fetchn(setup_schema.v.BehavioralSetup,'behavior_setup'))'];
            app.StimulusSetupDropDown.Items = ['' (fetchn(setup_schema.v.StimSetup,'stim_setup'))'];
            
            app.UITable.Data = cell(3,4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListBox = uilistbox(app.UIFigure);
            app.RecorderDropDownLabel = uilabel(app.UIFigure);
            app.RecorderDropDownLabel.Text = 'Recorder';
            app.RecorderDropDown = uidropdown(app.UIFigure);
            app.ProjectDropDownLabel = uilabel(app.UIFigure);
            app.ProjectDropDownLabel.Text = 'Project';
            app.ProjectDropDown = uidropdown(app.UIFigure);
            app.ProjectDropDown.Items = {};
            app.ProjectDropDown.Value = {};
            app.ServerLocationEditFieldLabel = uilabel(app.UIFigure);
            app.ServerLocationEditFieldLabel.Text = 'Server Location';
            app.ServerLocationEditField = uieditfield(app.UIFigure, 'text');
            app.BackupLocationEditFieldLabel = uilabel(app.UIFigure);
            app.BackupLocationEditFieldLabel.Text = 'Backup Location';
            app.BackupLocationEditField = uieditfield(app.UIFigure, 'text');
            app.Panel = uipanel(app.UIFigure);
            app.NoteEditFieldLabel = uilabel(app.Panel);
            app.NoteEditFieldLabel.Text = 'Note';
            app.NoteEditField = uieditfield(app.Panel, 'text');
            app.RunConditionDropDownLabel = uilabel(app.Panel);
            app.RunConditionDropDownLabel.Text = 'Run Condition';
            app.RunConditionDropDown = uidropdown(app.Panel);
            app.RunConditionDropDown.Items = {'Unknown', 'Successful', 'Test', 'Failed Run'};
            app.RunConditionDropDown.Value = 'Unknown';
            app.SpecimenEditFieldLabel = uilabel(app.Panel);
            app.SpecimenEditFieldLabel.Text = 'Specimen';
            app.SpecimenEditField = uieditfield(app.Panel, 'text');
            app.DualColourCheckBox = uicheckbox(app.Panel);
            app.DualColourCheckBox.Text = 'Dual Colour';
            app.ScanSizeGBEditFieldLabel = uilabel(app.Panel);
            app.ScanSizeGBEditFieldLabel.Text = 'Scan Size (GB)';
            app.ScanSizeGBEditField = uieditfield(app.Panel, 'numeric');
            app.ScanStatusDropDownLabel = uilabel(app.Panel);
            app.ScanStatusDropDownLabel.Text = 'Scan Status';
            app.ScanStatusDropDown = uidropdown(app.Panel);
            app.ScanStatusDropDown.Items = {'Successful', 'Interrupted', 'Null'};
            app.ScanStatusDropDown.Value = 'Successful';
            app.ScannerTypeDropDownLabel = uilabel(app.Panel);
            app.ScannerTypeDropDownLabel.Text = 'Scanner Type';
            app.ScannerTypeDropDown = uidropdown(app.Panel);
            app.ScannerTypeDropDown.Items = {'', 'HR', 'Single Frame', 'Stage Scan'};
            app.ScannerTypeDropDown.Value = '';
            app.OrganDropDownLabel = uilabel(app.Panel);
            app.OrganDropDownLabel.Text = 'Organ';
            app.OrganDropDown = uidropdown(app.Panel);
            app.OrganDropDown.Items = {};
            app.OrganDropDown.Value = {};
            app.BehavioralSetupDropDownLabel = uilabel(app.Panel);
            app.BehavioralSetupDropDownLabel.Text = 'Behavioral Setup';
            app.BehavioralSetupDropDown = uidropdown(app.Panel);
            app.BehavioralSetupDropDown.Items = {};
            app.BehavioralSetupDropDown.Value = {};
            app.StimulusSetupDropDownLabel = uilabel(app.Panel);
            app.StimulusSetupDropDownLabel.Text = 'Stimulus Setup';
            app.StimulusSetupDropDown = uidropdown(app.Panel);
            app.StimulusSetupDropDown.Items = {};
            app.StimulusSetupDropDown.Value = {};
            app.FilterEditFieldLabel = uilabel(app.Panel);
            app.FilterEditFieldLabel.Text = 'Filter';
            app.FilterEditField = uieditfield(app.Panel, 'text');
            app.Panel_2 = uipanel(app.Panel);
            app.UnitDropDownLabel = uilabel(app.Panel_2);
            app.UnitDropDownLabel.Text = 'Unit';
            app.UnitDropDown = uidropdown(app.Panel_2);
            app.UnitDropDown.Items = {'Unknown', 'Hours', 'Days', 'Months', 'Years', 'Instar'};
            app.UnitDropDown.Value = 'Unknown';
            app.AgeEditFieldLabel = uilabel(app.Panel_2);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.Panel_2, 'numeric');
            app.DevelopmentStageDropDownLabel = uilabel(app.Panel_2);
            app.DevelopmentStageDropDownLabel.Text = 'Development Stage';
            app.DevelopmentStageDropDown = uidropdown(app.Panel_2);
            app.DevelopmentStageDropDown.Items = {'Larva', 'Adult', 'Embryo', 'Others'};
            app.DevelopmentStageDropDown.Value = 'Larva';
            app.TabGroup = uitabgroup(app.Panel);
            app.Camera1Tab = uitab(app.TabGroup);
            app.Camera1Tab.Title = 'Camera 1';
            app.CameraDropDownLabel = uilabel(app.Camera1Tab);
            app.CameraDropDownLabel.Text = 'Camera';
            app.CameraDropDown = uidropdown(app.Camera1Tab);
            app.TubeLensDropDownLabel = uilabel(app.Camera1Tab);
            app.TubeLensDropDownLabel.Text = 'Tube Lens';
            app.TubeLensDropDown = uidropdown(app.Camera1Tab);
            app.FocalLengthZoomEditFieldLabel = uilabel(app.Camera1Tab);
            app.FocalLengthZoomEditFieldLabel.Text = 'Focal Length (Zoom)';
            app.FocalLengthZoomEditField = uieditfield(app.Camera1Tab, 'numeric');
            app.Camera2Tab = uitab(app.TabGroup);
            app.Camera2Tab.Title = 'Camera 2';
            app.CameraDropDown_2Label = uilabel(app.Camera2Tab);
            app.CameraDropDown_2Label.Text = 'Camera';
            app.CameraDropDown_2 = uidropdown(app.Camera2Tab);
            app.TubeLensDropDown_2Label = uilabel(app.Camera2Tab);
            app.TubeLensDropDown_2Label.Text = 'Tube Lens';
            app.TubeLensDropDown_2 = uidropdown(app.Camera2Tab);
            app.FocalLengthZoomEditField_2Label = uilabel(app.Camera2Tab);
            app.FocalLengthZoomEditField_2Label.Text = 'Focal Length (Zoom)';
            app.FocalLengthZoomEditField_2 = uieditfield(app.Camera2Tab, 'numeric');
            app.DeployCheckBox = uicheckbox(app.Camera2Tab);
            app.DeployCheckBox.Text = 'Deploy';
            app.SCAPEConfigDropDownLabel = uilabel(app.Panel);
            app.SCAPEConfigDropDownLabel.Text = 'SCAPE Config';
            app.SCAPEConfigDropDown = uidropdown(app.Panel);
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {'Laser'; 'Purpose'; 'Specimen Power'};
            app.UITable2 = uitable(app.Panel);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {'Channel'; 'Purpose'; 'Description'};
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.Text = 'Upload';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SCAPE_datajoint

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end